classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TextArea                     matlab.ui.control.TextArea
        FrecuenciainferiorEditField  matlab.ui.control.NumericEditField
        FrecuenciainferiorEditFieldLabel  matlab.ui.control.Label
        FrecuenciasuperiorEditField  matlab.ui.control.NumericEditField
        FrecuenciasuperiorEditFieldLabel  matlab.ui.control.Label
        ListBox                      matlab.ui.control.ListBox
        HzLabel_2                    matlab.ui.control.Label
        HzLabel                      matlab.ui.control.Label
        ListBox_2                    matlab.ui.control.ListBox
        CargarAudioButton            matlab.ui.control.Button
        UIAxes_3                     matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        tipo % Description
        sa % Audio 
        aud %Audio
        Fs %Frecuencia de muestreo
        mono %Audio monofonico
        nData %Cantidad de datos
        Fc  %Frecuencia de corte
        fc
        Ts % Tiempo de muestreo
        t 
        a
        b 
        tipoFilt %Lista de filtros
        tipo_index
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarAudioButton
        function CargarAudioButtonPushed(app, event)
            app.sa = uigetfile('*.mp3', 'Seleccione audio' );
            disp(app.sa)
            [app.aud, app.Fs] = audioread(app.sa);
            app.mono = (app.aud(:,1)+app.aud(:,2))/2;
            app.nData = 0:length(app.aud)-1;
            app.Ts = 1/app.Fs;
            app.t = app.nData*app.Ts; 
            
            
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            app.tipoFilt= ["low","high","bandpass","stop"];
            switch value
                case 'Pasa Baja'
                app.tipo_index = 1;
                app.FrecuenciainferiorEditField.Enable = 'off';
                app.FrecuenciasuperiorEditFieldLabel.Text = 'Frecuencia de corte';
                case 'Pasa Alta'
                app.tipo_index = 2;
                app.FrecuenciainferiorEditField.Enable = 'off';
                app.FrecuenciasuperiorEditFieldLabel.Text = 'Frecuencia de corte';
                %app.Fc= app.FrecuenciasuperiorEditField.Value/(app.Fs/2);
                %app.f= [0 app.Fc app.Fc+0.0001 1];
                case 'Pasa Banda' 
                app.tipo_index = 3;
                app.FrecuenciainferiorEditField.Enable = 'on';
                app.FrecuenciasuperiorEditFieldLabel.Text = 'Frecuencia superior';
                
                case 'Rechaza Banda'
                app.tipo_index = 4;
                app.FrecuenciainferiorEditField.Enable = 'on';
                app.FrecuenciasuperiorEditFieldLabel.Text = 'Frecuencia superior'
            end          
        end

        % Value changed function: FrecuenciasuperiorEditField
        function FrecuenciasuperiorEditFieldValueChanged(app, event)
            value = app.FrecuenciasuperiorEditField.Value;
        end

        % Value changed function: FrecuenciainferiorEditField
        function FrecuenciainferiorEditFieldValueChanged(app, event)
            value = app.FrecuenciainferiorEditField.Value;
        end

        % Value changed function: ListBox_2
        function ListBox_2ValueChanged(app, event)
            
            value = app.ListBox_2.Value;
            ord_IIR = 5;
            ord_FIR = 10;
            if app.tipo_index == 1
                app.tipo= app.tipoFilt(app.tipo_index);
                app.Fc= app.FrecuenciasuperiorEditField.Value/(app.Fs/2);
                app.fc=app.FrecuenciasuperiorEditField.Value;
                f = [0 app.Fc app.Fc+0.001 1];
                A = [1 1 0 0];
            elseif app.tipo_index == 2
                app.tipo= app.tipoFilt(app.tipo_index);
                app.Fc= app.FrecuenciasuperiorEditField.Value/(app.Fs/2);
                app.fc=app.FrecuenciasuperiorEditField.Value; 
                f = [0 app.Fc app.Fc+0.001 1];
                A = [0 0 1 1];
                
            elseif app.tipo_index == 3
                app.tipo= app.tipoFilt(app.tipo_index);
                app.fc=[app.FrecuenciasuperiorEditField.Value app.FrecuenciainferiorEditField.Value];
                app.Fc=app.fc./(app.Fs/2);
                f=[0 app.Fc(1) app.Fc(1)+0.001 app.Fc(2)+0.001 1];
                A=[0 0 1 1 0 0];
            elseif app.tipo_index == 4
                app.tipo= app.tipoFilt(app.tipo_index);
                app.fc = [app.FrecuenciasuperiorEditField.Value app.FrecuenciainferiorEditField.Value];
                app.Fc = app.fc./(app.Fs/2);
                f = [0 app.Fc(1) app.Fc(1)+0.001 app.Fc(2)+0.001 1];
                A = [1 1 0 0 1 1];
            end
                switch value

                    case 'Enventanado (FIR)'
                    app.a = 1;
                    ftype = rectwin(ord_FIR+1);
                    app.b= fir1(ord_FIR, app.Fc,app.tipo,ftype);
                    
                    case 'Muestreo en Frecuencia (FIR)'
                        app.a = 1;
                        app.b = fir2(ord_FIR,f,A);
                    case 'Parks-McClellan (FIR)'
                        app.a = 1;
                        app.b = firpm(ord_FIR,f,A);
                        
                    case 'Butterworth (IIR)'
                        disp(app.tipo)
                        [num,den] = butter(ord_IIR,2*pi*app.fc,app.tipo,'s');
                        [numd,dend] = bilinear(num,den,app.Fs); % Bilinear
                        app.b = numd;
                        app.a = dend;
                        
                    case 'Chebyshov I (IIR)'
                        
                        Rp = 0.5;
                        [num,den] = cheby1(ord_IIR,Rp,2*pi*app.fc,app.tipo,'s');
                        [numd,dend] = bilinear(num,den,app.Fs);
                        app.b = numd;
                        app.a = dend;
                        
                    case 'Chebyshov II (IIR)'
                        
                        Rs = 35; % Filtro Chevyshov II
                        [num,den] = cheby2(ord_IIR,Rs,2*pi*app.fc,app.tipo,'s');
                        [numd,dend] = bilinear(num,den,app.Fs); 
                        app.b = numd;
                        app.a = dend;
                        
                    case 'Elíptico (IIR)'
                        
                        Rp = 0.5;
                        Rs = 35;
                        disp(app.Fc)
                        [num,den] = ellip(ord_IIR,Rp,Rs,2*pi*app.fc,app.tipo,'s');
                        [numd,dend] = bilinear(num,den,app.Fs);
                        app.b = numd;
                        app.a = dend;
                
                otherwise
                    f = warndlg('Pressing ACCEPT clears memory','Warning');
                end
        
            
         %Respuesta en frecuencia
         try 
             [H, w] = freqz(app.b,app.a,app.Fs);
             plot(app.UIAxes,app.Fs*w/(2*pi),20*log10(abs(H))) % En funcion de f [Hz]   
             %Señal audio original vs señal filtrada
             filt = filter(app.b,app.a,app.mono);
             plot(app.UIAxes_2,  app.t, app.mono,'b', app.t,filt,'r') , axis tight 
         catch 
             f = warndlg('Seleccione el tipo de filtro','Warning'); 
         end

        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Respuesta en frecuencia')
            xlabel(app.UIAxes, 'Frecuencia (Hz)')
            ylabel(app.UIAxes, 'H(v) (dB)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Señal original vs Señal filtrada')
            xlabel(app.UIAxes_2, 'Tiempo (S)')
            ylabel(app.UIAxes_2, 'Amplitud')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Densidad espectral de energia')
            xlabel(app.UIAxes_3, 'Frecuencia (Hz)')
            ylabel(app.UIAxes_3, 'dB/Hz')
            zlabel(app.UIAxes_3, 'Z')
            app.CargarAudioButton = uibutton(app.UIFigure, 'push');
            app.CargarAudioButton.ButtonPushedFcn = createCallbackFcn(app, @CargarAudioButtonPushed, true);
            app.CargarAudioButton.Text = 'Cargar Audio';
            app.ListBox_2 = uilistbox(app.UIFigure);
            app.ListBox_2.Items = {'Enventanado (FIR)', 'Muestreo en Frecuencia (FIR)', 'Parks-McClellan (FIR)', 'Butterworth (IIR)', 'Chebyshov I (IIR)', 'Chebyshov II (IIR)', 'Elíptico (IIR)'};
            app.ListBox_2.ValueChangedFcn = createCallbackFcn(app, @ListBox_2ValueChanged, true);
            app.ListBox_2.Value = 'Parks-McClellan (FIR)';
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = 'Hz';
            app.HzLabel_2 = uilabel(app.UIFigure);
            app.HzLabel_2.Text = 'Hz';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'Pasa Alta', 'Pasa Baja', 'Pasa Banda', 'Rechaza Banda'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'Pasa Alta';
            app.FrecuenciasuperiorEditFieldLabel = uilabel(app.UIFigure);
            app.FrecuenciasuperiorEditFieldLabel.Text = 'Frecuencia superior';
            app.FrecuenciasuperiorEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrecuenciasuperiorEditField.ValueChangedFcn = createCallbackFcn(app, @FrecuenciasuperiorEditFieldValueChanged, true);
            app.FrecuenciainferiorEditFieldLabel = uilabel(app.UIFigure);
            app.FrecuenciainferiorEditFieldLabel.Text = 'Frecuencia inferior';
            app.FrecuenciainferiorEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrecuenciainferiorEditField.ValueChangedFcn = createCallbackFcn(app, @FrecuenciainferiorEditFieldValueChanged, true);
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'PASOS: 1. Ingrese el audio, 2. Ingrese el tipo de filtro, 3. Ingrese las frecuencias de corte segun sea el caso, 4. Ingrese la topologia del filtro'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end